classdef blqReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CurrentAxes                matlab.ui.control.UIAxes
        ConductanceAxes            matlab.ui.control.UIAxes
        blqReaderLabel             matlab.ui.control.Label
        LoadblqButton              matlab.ui.control.Button
        ButtonGroup                matlab.ui.container.ButtonGroup
        NormalizedButton           matlab.ui.control.RadioButton
        NotnormalizedButton        matlab.ui.control.RadioButton
        fromEditFieldLabel         matlab.ui.control.Label
        fromEditField              matlab.ui.control.NumericEditField
        fromEditFieldLabel_2       matlab.ui.control.Label
        toEditField                matlab.ui.control.NumericEditField
        toEditFieldLabel           matlab.ui.control.Label
        fromEditFieldLabel_3       matlab.ui.control.Label
        CurvestoshowLabel          matlab.ui.control.Label
        CurvestoshowEditField      matlab.ui.control.NumericEditField
        DerivativepointsLabel      matlab.ui.control.Label
        DerivativepointsEditField  matlab.ui.control.NumericEditField
        OffsetvoltageLabel         matlab.ui.control.Label
        OffsetvoltageEditField     matlab.ui.control.NumericEditField
        CalculateButton            matlab.ui.control.Button
        fromEditFieldLabel_4       matlab.ui.control.Label
        ChangesamplecurvesButton   matlab.ui.control.Button
        AnalyzeimagesButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp % main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
        end

        % Button pushed function: LoadblqButton
        function LoadblqButtonPushed(app, event)
            [app.CallingApp.Struct, app.CallingApp.MatrizCorriente, app.CallingApp.Voltaje] = loadblq(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [app.CallingApp.Struct] = calculateblq(app, app.CallingApp.Struct, app.CallingApp.Voltaje, app.CallingApp.MatrizCorriente);
        end

        % Button pushed function: ChangesamplecurvesButton
        function ChangesamplecurvesButtonPushed(app, event)
            [app.CallingApp.Struct] = changeCurves(app, app.CallingApp.Struct);
            CalculateButtonPushed(app)
        end

        % Button pushed function: AnalyzeimagesButton
        function AnalyzeimagesButtonPushed(app, event)
            [app.CallingApp.Struct] = analyzeImages(app.CallingApp.Struct);
            maps = num2str(length(app.CallingApp.Struct.Energia));
            uiwait(msgbox([maps ' maps succesfully generated'], 'You are almost there', 'help'))
            app.CallingApp.mapsPreviewApp = mapsPreview(app.CallingApp, app.CallingApp.Struct);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CurrentAxes = uiaxes(app.UIFigure);
            title(app.CurrentAxes, 'IV')
            xlabel(app.CurrentAxes, 'Bias Voltage (mV')
            ylabel(app.CurrentAxes, 'Tunneling current (nA)')
            app.ConductanceAxes = uiaxes(app.UIFigure);
            title(app.ConductanceAxes, 'dI/dV')
            xlabel(app.ConductanceAxes, 'Bias Voiltage (mV)')
            ylabel(app.ConductanceAxes, 'Conductance (\muS)')
            app.blqReaderLabel = uilabel(app.UIFigure);
            app.blqReaderLabel.Text = 'blqReader';
            app.LoadblqButton = uibutton(app.UIFigure, 'push');
            app.LoadblqButton.ButtonPushedFcn = createCallbackFcn(app, @LoadblqButtonPushed, true);
            app.LoadblqButton.Text = 'Load blq';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.NormalizedButton = uiradiobutton(app.ButtonGroup);
            app.NormalizedButton.Text = 'Normalized';
            app.NormalizedButton.Value = true;
            app.NotnormalizedButton = uiradiobutton(app.ButtonGroup);
            app.NotnormalizedButton.Text = 'Not normalized';
            app.fromEditFieldLabel = uilabel(app.UIFigure);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.UIFigure, 'numeric');
            app.fromEditFieldLabel_2 = uilabel(app.UIFigure);
            app.fromEditFieldLabel_2.Text = 'mV';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.fromEditFieldLabel_3 = uilabel(app.UIFigure);
            app.fromEditFieldLabel_3.Text = 'mV';
            app.CurvestoshowLabel = uilabel(app.UIFigure);
            app.CurvestoshowLabel.Text = 'Curves to show:';
            app.CurvestoshowEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurvestoshowEditField.RoundFractionalValues = 'on';
            app.CurvestoshowEditField.Value = 1;
            app.DerivativepointsLabel = uilabel(app.UIFigure);
            app.DerivativepointsLabel.Text = 'Derivative points:';
            app.DerivativepointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DerivativepointsEditField.Value = 1;
            app.OffsetvoltageLabel = uilabel(app.UIFigure);
            app.OffsetvoltageLabel.Text = 'Offset voltage:';
            app.OffsetvoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.fromEditFieldLabel_4 = uilabel(app.UIFigure);
            app.fromEditFieldLabel_4.Text = 'mV';
            app.ChangesamplecurvesButton = uibutton(app.UIFigure, 'push');
            app.ChangesamplecurvesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangesamplecurvesButtonPushed, true);
            app.ChangesamplecurvesButton.Text = 'Change sample curves';
            app.AnalyzeimagesButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeimagesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeimagesButtonPushed, true);
            app.AnalyzeimagesButton.Text = {'Analyze'; 'images'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blqReader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end