classdef blqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        blqAnalysisUIFigure      matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        OpenTab                  matlab.ui.container.Tab
        NewButton                matlab.ui.control.Button
        SaveInfostructButton     matlab.ui.control.Button
        AnalizeTab               matlab.ui.container.Tab
        LoadInfostructButton     matlab.ui.control.Button
        RealMapsButton           matlab.ui.control.Button
        FFTMapsButton            matlab.ui.control.Button
        ColormapRealDropDown     matlab.ui.control.DropDown
        ColormapFFTDropDown      matlab.ui.control.DropDown
        KeepChangesButton        matlab.ui.control.Button
        RemoveCentralLineButton  matlab.ui.control.Button
        blqAnalysisLabel         matlab.ui.control.Label
    end


    properties (Access = public)
        Property % Description
        % Variables de la aplicación
        InfoStruct
        InfoStructAnalisis
        InfoStructAnalisisCopy
        
        fourierImage
        
        TransformadasAnalisis
        TransformadasAnalisisCopy
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            genpath('Functions');
            addpath(genpath('Functions'));   
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            ImageAnalysisIV
        end

        % Button pushed function: SaveInfostructButton
        function SaveInfostructButtonPushed(app, event)
            saveInfo()
        end

        % Button pushed function: LoadInfostructButton
        function LoadInfostructButtonPushed(app, event)
            app.InfoStruct = openInfo();
%             app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
        end

        % Button pushed function: FFTMapsButton
        function FFTMapsButtonPushed(app, event)
            app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
            app.InfoStructAnalisis.Colormap = eval(app.ColormapFFTDropDown.Value);
            app.InfoStructAnalisisCopy.Colormap = eval(app.ColormapFFTDropDown.Value);
            VisualizeCell(app.InfoStruct.Transformadas,app.InfoStruct)
            app.fourierImage = gcf;
%             app.fourierImage.UserData.Colormap = app.InfoStruct.Colormap;
            app.fourierImage.Colormap = eval(app.ColormapFFTDropDown.Value);

            app.InfoStruct = app.fourierImage.UserData;
            app.InfoStructAnalisis = app.fourierImage.UserData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
            
%             app.TransformadasAnalisis = app.InfoStruct.Transformadas;                             
        end

        % Button pushed function: KeepChangesButton
        function KeepChangesButtonPushed(app, event)
             app.InfoStructAnalisis   = app.fourierImage.UserData;      
             app.InfoStructAnalisis.Transformadas = app.InfoStructAnalisisCopy.Transformadas; 
             
             app.InfoStructAnalisisCopy.Contrast = app.fourierImage.UserData.Contrast;
            
%             app.TransformadasAnalisis = app.TransformadasAnalisisCopy;
        end

        % Button pushed function: RemoveCentralLineButton
        function RemoveCentralLineButtonPushed(app, event)
            [app.InfoStructAnalisisCopy.Transformadas] = RemoveCentralLine(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Value changed function: ColormapFFTDropDown
        function ColormapFFTDropDownValueChanged(app, event)
            value = app.ColormapFFTDropDown.Value;
            app.fourierImage.Colormap = eval(value);
            
            app.fourierImage.UserData.Colormap = eval(value);
            app.InfoStruct.Colormap = eval(value);
            app.InfoStructAnalisis.Colormap = eval(value);
            app.InfoStructAnalisisCopy.Colormap = eval(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.blqAnalysisUIFigure = uifigure('Visible', 'off');
            app.blqAnalysisUIFigure.Name = 'blqAnalysis';
            app.TabGroup = uitabgroup(app.blqAnalysisUIFigure);
            app.OpenTab = uitab(app.TabGroup);
            app.OpenTab.Title = 'Open';
            app.NewButton = uibutton(app.OpenTab, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Tooltip = {'Abrir'};
            app.NewButton.Text = 'New';
            app.SaveInfostructButton = uibutton(app.OpenTab, 'push');
            app.SaveInfostructButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInfostructButtonPushed, true);
            app.SaveInfostructButton.Text = 'Save Infostruct';
            app.AnalizeTab = uitab(app.TabGroup);
            app.AnalizeTab.Title = 'Analize';
            app.LoadInfostructButton = uibutton(app.AnalizeTab, 'push');
            app.LoadInfostructButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfostructButtonPushed, true);
            app.LoadInfostructButton.Text = 'Load Infostruct';
            app.RealMapsButton = uibutton(app.AnalizeTab, 'push');
            app.RealMapsButton.Text = 'Real Maps';
            app.FFTMapsButton = uibutton(app.AnalizeTab, 'push');
            app.FFTMapsButton.ButtonPushedFcn = createCallbackFcn(app, @FFTMapsButtonPushed, true);
            app.FFTMapsButton.Text = 'FFT Maps';
            app.ColormapRealDropDown = uidropdown(app.AnalizeTab);
            app.ColormapRealDropDown.Items = {'gray', 'parula', 'jet', 'winter', 'hot', 'Spring', 'Summer', 'Autumn', 'Bone', 'Copper', 'Pink', 'Red', 'Green', 'Blue'};
            app.ColormapRealDropDown.Value = 'gray';
            app.ColormapFFTDropDown = uidropdown(app.AnalizeTab);
            app.ColormapFFTDropDown.Items = {'gray', 'parula', 'jet', 'winter', 'hot', 'spring', 'summer', 'autumn', 'bone', 'copper', 'pink', 'red', 'green', 'blue'};
            app.ColormapFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapFFTDropDownValueChanged, true);
            app.ColormapFFTDropDown.Value = 'gray';
            app.KeepChangesButton = uibutton(app.AnalizeTab, 'push');
            app.KeepChangesButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesButtonPushed, true);
            app.KeepChangesButton.Text = 'Keep Changes';
            app.RemoveCentralLineButton = uibutton(app.AnalizeTab, 'push');
            app.RemoveCentralLineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCentralLineButtonPushed, true);
            app.RemoveCentralLineButton.Text = 'Remove Central Line';
            app.blqAnalysisLabel = uilabel(app.blqAnalysisUIFigure);
            app.blqAnalysisLabel.Text = 'blqAnalysis';
            % Show the figure after all components are created
            app.blqAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blqApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.blqAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.blqAnalysisUIFigure)
        end
    end
end